classdef TireAnalyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Panel                          matlab.ui.container.Panel
        LoadSensitivityPanel           matlab.ui.container.Panel
        PpsiDropDown_2Label            matlab.ui.control.Label
        FZPDropDown                    matlab.ui.control.DropDown
        IAdegDropDown_2Label           matlab.ui.control.Label
        FZIADropDown                   matlab.ui.control.DropDown
        SAdirDropDownLabel_2           matlab.ui.control.Label
        FZSAdirDropDown                matlab.ui.control.DropDown
        FZAxes                         matlab.ui.control.UIAxes
        SASliderPanel                  matlab.ui.container.Panel
        UBdegLabel                     matlab.ui.control.Label
        SASlider                       matlab.ui.control.Slider
        LBdegLabel                     matlab.ui.control.Label
        FixDropDownLabel               matlab.ui.control.Label
        FixDropDown                    matlab.ui.control.DropDown
        PacejkafitsPanel               matlab.ui.container.Panel
        PpsiLabel                      matlab.ui.control.Label
        PacejkaPDropDown               matlab.ui.control.DropDown
        IAdegLabel                     matlab.ui.control.Label
        PacejkaIADropDown              matlab.ui.control.DropDown
        FZNDropDownLabel               matlab.ui.control.Label
        PacejkaFZDropDown              matlab.ui.control.DropDown
        SAdirDropDownLabel             matlab.ui.control.Label
        PacejkaSAdirDropDown           matlab.ui.control.DropDown
        PacejkaFitAxes                 matlab.ui.control.UIAxes
        CambersensitivityPanel         matlab.ui.container.Panel
        CamberSensitivityAxes          matlab.ui.control.UIAxes
        PressuresensitivityPanel       matlab.ui.container.Panel
        PressureSensitivityAxes        matlab.ui.control.UIAxes
        StatusPanel                    matlab.ui.container.Panel
        PressureStatusLabel            matlab.ui.control.Label
        CamberStatusLabel              matlab.ui.control.Label
        LoadStatusLabel                matlab.ui.control.Label
        TitlePanel                     matlab.ui.container.Panel
        TireAnalyzerLabel              matlab.ui.control.Label
        ByMatthewDrummondStoylesLabel  matlab.ui.control.Label
        RFRImage                       matlab.ui.control.Image
        Panel_2                        matlab.ui.container.Panel
        TireDropDownLabel              matlab.ui.control.Label
        TireDropDown                   matlab.ui.control.DropDown
        LoadsensitivityorderSpinnerLabel  matlab.ui.control.Label
        LoadSensitivityOrderSpinner    matlab.ui.control.Spinner
        RunButton                      matlab.ui.control.Button
        SlipanglebounddegLabel         matlab.ui.control.Label
        SABoundEditField               matlab.ui.control.NumericEditField
        SlipanglestepsizedegEditFieldLabel  matlab.ui.control.Label
        SAStepSizeEditField            matlab.ui.control.NumericEditField
        LoadstepsizeNEditFieldLabel    matlab.ui.control.Label
        LoadStepSizeEditField          matlab.ui.control.NumericEditField
        LoadupperboundNEditFieldLabel  matlab.ui.control.Label
        LoadUBEditField                matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        % Settings
        SABounds % Slip angle bounds
        LoadBounds % Load bounds
        d_SA % Slip angle step size
        d_load % Load step size
        
        % Data files info
        n_files % Number of data files
        n_tires
        FileNames
        TireNamesRaw
        TireNames
        TireData struct % Tire data variables
    end
    
    methods (Access = private)
        
        function fit_pressure_group(app, start_index)
            
        end
        
        function fit_camber_group(app, start_index)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Load files in tire data directory
            folder_contents = what('TTC Tire Data\');
            % Only list .mat files
            mat_files = folder_contents.mat;
            app.FileNames = mat_files;
            % Number of files
            app.n_files = length(mat_files);
            % Load each tire's name into the array
            % At some point, we can parse the tire info from the string
            app.TireNamesRaw = cell(app.n_files);
            for i = 1:length(mat_files)
                tire_name_struct = load(strcat('TTC Tire Data/', ...
                    mat_files{i}), 'tireid');
                tire_name = tire_name_struct.tireid;
                app.TireNamesRaw{i} = tire_name;
                if exist('tire_names','var') ...
                        && ~ismember(tire_name, tire_names)
                    tire_names{length(tire_names)+1} = tire_name;
                elseif ~exist('tire_names','var')
                    tire_names = {tire_name};
                end
            end
            app.n_tires = length(tire_names);
            app.TireNames = tire_names;
            app.TireDropDown.Items = tire_names;
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            % Load settings
            bound = app.SABoundEditField.Value;
            app.SABounds = [-bound bound];
            app.LoadBounds = [-app.LoadUBEditField.Value 0];
            app.d_SA = app.SAStepSizeEditField.Value;
            app.d_load = app.LoadStepSizeEditField.Value;
            
            % Load TTC data files
            % Load all the data into a cell array of structs
            found_index = 1;
            for i = 1:app.n_files
                file_name = app.FileNames{i};
                tire_name = app.TireNamesRaw{i};
                if strcmp(tire_name, app.TireDropDown.Value)
                    found_data{found_index} = load(strcat('TTC Tire Data/', file_name));
                    found_index = found_index + 1;
                end
            end
            % Concatenate the data from different files but same tire
            field_names = fieldnames(found_data{1});
            for i = 1:numel(field_names)
                for j = 1:length(found_data)
                    if isfield(app.TireData, field_names{i}) ...
                            && ~strcmp(field_names{i}, 'channel') ...
                            && ~strcmp(field_names{i}, 'source') ...
                            && ~strcmp(field_names{i}, 'testid') ...
                            && ~strcmp(field_names{i}, 'tireid')
                        app.TireData.(field_names{i}) = ...
                            [app.TireData.(field_names{i}); ...
                            found_data{j}.(field_names{i})];
                    else
                        app.TireData(1).(field_names{i}) = ...
                            found_data{j}.(field_names{i});
                    end
                end
            end
            % Group pressure data
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.LoadSensitivityPanel = uipanel(app.Panel);
            app.LoadSensitivityPanel.Title = 'Load sensitivity';
            app.PpsiDropDown_2Label = uilabel(app.LoadSensitivityPanel);
            app.PpsiDropDown_2Label.Text = 'P (psi):';
            app.FZPDropDown = uidropdown(app.LoadSensitivityPanel);
            app.FZPDropDown.Items = {'8', '10', '12', '14'};
            app.FZPDropDown.Value = '8';
            app.IAdegDropDown_2Label = uilabel(app.LoadSensitivityPanel);
            app.IAdegDropDown_2Label.Text = 'IA (deg):';
            app.FZIADropDown = uidropdown(app.LoadSensitivityPanel);
            app.FZIADropDown.Items = {'0', '2', '4'};
            app.FZIADropDown.Value = '0';
            app.SAdirDropDownLabel_2 = uilabel(app.LoadSensitivityPanel);
            app.SAdirDropDownLabel_2.Text = 'SA dir:';
            app.FZSAdirDropDown = uidropdown(app.LoadSensitivityPanel);
            app.FZSAdirDropDown.Items = {'+', '-'};
            app.FZSAdirDropDown.Value = '+';
            app.FZAxes = uiaxes(app.LoadSensitivityPanel);
            title(app.FZAxes, 'Load sensitivity and fit at SA=Xdeg')
            xlabel(app.FZAxes, 'Load (N)')
            ylabel(app.FZAxes, 'Lateral coefficient')
            app.SASliderPanel = uipanel(app.LoadSensitivityPanel);
            app.UBdegLabel = uilabel(app.SASliderPanel);
            app.UBdegLabel.Text = '15 deg';
            app.SASlider = uislider(app.SASliderPanel);
            app.SASlider.Limits = [-15 15];
            app.SASlider.MajorTicks = [-15 0 15];
            app.SASlider.MajorTickLabels = {''};
            app.LBdegLabel = uilabel(app.SASliderPanel);
            app.LBdegLabel.Text = '-15 deg';
            app.FixDropDownLabel = uilabel(app.LoadSensitivityPanel);
            app.FixDropDownLabel.Text = 'Fix:';
            app.FixDropDown = uidropdown(app.LoadSensitivityPanel);
            app.FixDropDown.Items = {'IA', 'P'};
            app.FixDropDown.Value = 'IA';
            app.PacejkafitsPanel = uipanel(app.Panel);
            app.PacejkafitsPanel.Title = 'Pacejka fits';
            app.PpsiLabel = uilabel(app.PacejkafitsPanel);
            app.PpsiLabel.Text = 'P (psi):';
            app.PacejkaPDropDown = uidropdown(app.PacejkafitsPanel);
            app.PacejkaPDropDown.Items = {'8', '10', '12', '14'};
            app.PacejkaPDropDown.Value = '8';
            app.IAdegLabel = uilabel(app.PacejkafitsPanel);
            app.IAdegLabel.Text = 'IA (deg):';
            app.PacejkaIADropDown = uidropdown(app.PacejkafitsPanel);
            app.PacejkaIADropDown.Items = {'0', '2', '4'};
            app.PacejkaIADropDown.Value = '0';
            app.FZNDropDownLabel = uilabel(app.PacejkafitsPanel);
            app.FZNDropDownLabel.Text = 'FZ (N)';
            app.PacejkaFZDropDown = uidropdown(app.PacejkafitsPanel);
            app.PacejkaFZDropDown.Items = {};
            app.PacejkaFZDropDown.Value = {};
            app.SAdirDropDownLabel = uilabel(app.PacejkafitsPanel);
            app.SAdirDropDownLabel.Text = 'SA dir:';
            app.PacejkaSAdirDropDown = uidropdown(app.PacejkafitsPanel);
            app.PacejkaSAdirDropDown.Items = {'+', '-'};
            app.PacejkaSAdirDropDown.Value = '+';
            app.PacejkaFitAxes = uiaxes(app.PacejkafitsPanel);
            title(app.PacejkaFitAxes, 'Pacejka fit')
            xlabel(app.PacejkaFitAxes, 'X')
            ylabel(app.PacejkaFitAxes, 'Y')
            app.CambersensitivityPanel = uipanel(app.Panel);
            app.CambersensitivityPanel.Title = 'Camber sensitivity';
            app.CamberSensitivityAxes = uiaxes(app.CambersensitivityPanel);
            title(app.CamberSensitivityAxes, 'Title')
            xlabel(app.CamberSensitivityAxes, 'X')
            ylabel(app.CamberSensitivityAxes, 'Y')
            app.PressuresensitivityPanel = uipanel(app.Panel);
            app.PressuresensitivityPanel.Title = 'Pressure sensitivity';
            app.PressureSensitivityAxes = uiaxes(app.PressuresensitivityPanel);
            title(app.PressureSensitivityAxes, 'Title')
            xlabel(app.PressureSensitivityAxes, 'X')
            ylabel(app.PressureSensitivityAxes, 'Y')
            app.StatusPanel = uipanel(app.UIFigure);
            app.StatusPanel.Title = 'Status';
            app.PressureStatusLabel = uilabel(app.StatusPanel);
            app.CamberStatusLabel = uilabel(app.StatusPanel);
            app.CamberStatusLabel.Text = 'Label2';
            app.LoadStatusLabel = uilabel(app.StatusPanel);
            app.LoadStatusLabel.Text = 'Label3';
            app.TitlePanel = uipanel(app.UIFigure);
            app.TireAnalyzerLabel = uilabel(app.TitlePanel);
            app.TireAnalyzerLabel.Text = 'Tire Analyzer';
            app.ByMatthewDrummondStoylesLabel = uilabel(app.TitlePanel);
            app.ByMatthewDrummondStoylesLabel.Text = 'By Matthew Drummond-Stoyles';
            app.RFRImage = uiimage(app.TitlePanel);
            app.RFRImage.ImageSource = 'RFRlogo.png';
            app.Panel_2 = uipanel(app.UIFigure);
            app.TireDropDownLabel = uilabel(app.Panel_2);
            app.TireDropDownLabel.Text = 'Tire';
            app.TireDropDown = uidropdown(app.Panel_2);
            app.LoadsensitivityorderSpinnerLabel = uilabel(app.Panel_2);
            app.LoadsensitivityorderSpinnerLabel.Text = 'Load sensitivity order';
            app.LoadSensitivityOrderSpinner = uispinner(app.Panel_2);
            app.LoadSensitivityOrderSpinner.Tooltip = {'Polynomial order that will be fit to the load sensitivity data.'};
            app.RunButton = uibutton(app.Panel_2, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.SlipanglebounddegLabel = uilabel(app.Panel_2);
            app.SlipanglebounddegLabel.Text = 'Slip angle bound (deg):';
            app.SABoundEditField = uieditfield(app.Panel_2, 'numeric');
            app.SABoundEditField.Value = 15;
            app.SlipanglestepsizedegEditFieldLabel = uilabel(app.Panel_2);
            app.SlipanglestepsizedegEditFieldLabel.Text = 'Slip angle step size (deg):';
            app.SAStepSizeEditField = uieditfield(app.Panel_2, 'numeric');
            app.SAStepSizeEditField.Value = 0.1;
            app.LoadstepsizeNEditFieldLabel = uilabel(app.Panel_2);
            app.LoadstepsizeNEditFieldLabel.Text = 'Load step size (N):';
            app.LoadStepSizeEditField = uieditfield(app.Panel_2, 'numeric');
            app.LoadStepSizeEditField.Value = 20;
            app.LoadupperboundNEditFieldLabel = uilabel(app.Panel_2);
            app.LoadupperboundNEditFieldLabel.Text = 'Load upper bound (N):';
            app.LoadUBEditField = uieditfield(app.Panel_2, 'numeric');
            app.LoadUBEditField.Value = 1200;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TireAnalyzer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end