classdef TireAnalyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TireAnalyzerUIFigure           matlab.ui.Figure
        TotalGridLayout                matlab.ui.container.GridLayout
        BottomGridLayout               matlab.ui.container.GridLayout
        RunOptionsPanel                matlab.ui.container.Panel
        GridLayout8                    matlab.ui.container.GridLayout
        Panel_21                       matlab.ui.container.Panel
        CreatefitsCheckBox             matlab.ui.control.CheckBox
        Panel_20                       matlab.ui.container.Panel
        SaveFyandMzdataCheckBox        matlab.ui.control.CheckBox
        Panel_19                       matlab.ui.container.Panel
        TireDropDown                   matlab.ui.control.DropDown
        TireLabel                      matlab.ui.control.Label
        Panel_11                       matlab.ui.container.Panel
        GridLayout7                    matlab.ui.container.GridLayout
        RoundCheckBox                  matlab.ui.control.CheckBox
        ManufacturerCheckBox           matlab.ui.control.CheckBox
        ODCheckbox                     matlab.ui.control.CheckBox
        TireWidthCheckBox              matlab.ui.control.CheckBox
        RimODCheckBox                  matlab.ui.control.CheckBox
        CompoundCheckBox               matlab.ui.control.CheckBox
        RimWidthCheckBox               matlab.ui.control.CheckBox
        Panel_12                       matlab.ui.container.Panel
        RoundDropDownLabel             matlab.ui.control.Label
        RoundDropDown                  matlab.ui.control.DropDown
        Panel_13                       matlab.ui.container.Panel
        ManufacturerDropDownLabel      matlab.ui.control.Label
        ManufacturerDropDown           matlab.ui.control.DropDown
        Panel_14                       matlab.ui.container.Panel
        OuterdiameterDropDownLabel     matlab.ui.control.Label
        OuterdiameterDropDown          matlab.ui.control.DropDown
        Panel_15                       matlab.ui.container.Panel
        TirewidthDropDownLabel         matlab.ui.control.Label
        TirewidthDropDown              matlab.ui.control.DropDown
        Panel_16                       matlab.ui.container.Panel
        RimouterdiameterDropDownLabel  matlab.ui.control.Label
        RimouterdiameterDropDown       matlab.ui.control.DropDown
        Panel_17                       matlab.ui.container.Panel
        CompoundDropDownLabel          matlab.ui.control.Label
        CompoundDropDown               matlab.ui.control.DropDown
        Panel_18                       matlab.ui.container.Panel
        RimwidthDropDownLabel          matlab.ui.control.Label
        RimwidthDropDown               matlab.ui.control.DropDown
        RunButton                      matlab.ui.control.Button
        ButtonGroup                    matlab.ui.container.ButtonGroup
        SelectfromdropdownButton       matlab.ui.control.RadioButton
        SelectindividualparametersButton  matlab.ui.control.RadioButton
        FitOptionsPanel                matlab.ui.container.Panel
        LoadsensitivityorderLabel      matlab.ui.control.Label
        LoadSensitivityOrderSpinner    matlab.ui.control.Spinner
        SlipanglebounddegLabel         matlab.ui.control.Label
        SABoundEditField               matlab.ui.control.NumericEditField
        SlipanglestepsizedegEditFieldLabel  matlab.ui.control.Label
        SAStepSizeEditField            matlab.ui.control.NumericEditField
        LoadstepsizeNEditFieldLabel    matlab.ui.control.Label
        LoadStepSizeEditField          matlab.ui.control.NumericEditField
        LoadupperboundNEditFieldLabel  matlab.ui.control.Label
        LoadUBEditField                matlab.ui.control.NumericEditField
        PacejkafitmaxiterationsEditFieldLabel  matlab.ui.control.Label
        PacejkafitmaxiterationsEditField  matlab.ui.control.NumericEditField
        PacejkaalgorithmDropDownLabel  matlab.ui.control.Label
        PacejkaalgorithmDropDown       matlab.ui.control.DropDown
        SeparateSACheckBox             matlab.ui.control.CheckBox
        ScalingfactorsPanel            matlab.ui.container.Panel
        UsedifferentXandYscalingfactorsCheckBox  matlab.ui.control.CheckBox
        ScalingfactorLabel             matlab.ui.control.Label
        XscalingfactorEditField        matlab.ui.control.NumericEditField
        ForcecoefficientYscalingfactorEditFieldLabel  matlab.ui.control.Label
        YscalingfactorEditField        matlab.ui.control.NumericEditField
        PlotsPanel                     matlab.ui.container.Panel
        GridLayout                     matlab.ui.container.GridLayout
        PacejkaFitsPanel               matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        PacejkaClickRunLabel           matlab.ui.control.Label
        GridLayout4                    matlab.ui.container.GridLayout
        Panel_5                        matlab.ui.container.Panel
        PacejkaVaryDropDownLabel       matlab.ui.control.Label
        PacejkaVaryDropDown            matlab.ui.control.DropDown
        Panel_6                        matlab.ui.container.Panel
        PacejkaBothIADropDown          matlab.ui.control.DropDown
        PacejkaBothIADropDownLabel     matlab.ui.control.Label
        PacejkaOnlyIADropDown          matlab.ui.control.DropDown
        PacejkaOnlyIADropDownLabel     matlab.ui.control.Label
        Panel_7                        matlab.ui.container.Panel
        PacejkaBothPDropDown           matlab.ui.control.DropDown
        PacejkaBothPDropDownLabel      matlab.ui.control.Label
        PacejkaOnlyPDropDown           matlab.ui.control.DropDown
        PacejkaOnlyPDropDownLabel      matlab.ui.control.Label
        Panel_8                        matlab.ui.container.Panel
        PacejkaFZDropDown              matlab.ui.control.DropDown
        PacejkaFZDropDownLabel         matlab.ui.control.Label
        Panel_9                        matlab.ui.container.Panel
        PacejkaSAdirDropDown           matlab.ui.control.DropDown
        PacejkaSAdirDropDownLabel      matlab.ui.control.Label
        PacejkaFitNotCompletedLabel    matlab.ui.control.Label
        PacejkaFitAxes                 matlab.ui.control.UIAxes
        CamberSensitivityPanel         matlab.ui.container.Panel
        GridLayout9                    matlab.ui.container.GridLayout
        NotimplementedyetLabel         matlab.ui.control.Label
        PressureSensitivityPanel       matlab.ui.container.Panel
        GridLayout10                   matlab.ui.container.GridLayout
        NotimplementedyetLabel_2       matlab.ui.control.Label
        LoadSensitivityPanel           matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        GridLayout2_2                  matlab.ui.container.GridLayout
        Panel                          matlab.ui.container.Panel
        VaryDropDownLabel              matlab.ui.control.Label
        FZVaryDropDown                 matlab.ui.control.DropDown
        Panel_2                        matlab.ui.container.Panel
        IAdegDropDown_2Label           matlab.ui.control.Label
        FZIADropDown                   matlab.ui.control.DropDown
        Panel_3                        matlab.ui.container.Panel
        PpsiDropDown_2Label            matlab.ui.control.Label
        FZPDropDown                    matlab.ui.control.DropDown
        Panel_4                        matlab.ui.container.Panel
        SAdirDropDownLabel             matlab.ui.control.Label
        FZSAdirDropDown                matlab.ui.control.DropDown
        GridLayout6                    matlab.ui.container.GridLayout
        LBdegLabel                     matlab.ui.control.Label
        SASlider                       matlab.ui.control.Slider
        UBdegLabel                     matlab.ui.control.Label
        FZClickRunLabel                matlab.ui.control.Label
        FZAxes                         matlab.ui.control.UIAxes
        TopGridLayout                  matlab.ui.container.GridLayout
        TitlePanel                     matlab.ui.container.Panel
        GridLayout11                   matlab.ui.container.GridLayout
        Panel_22                       matlab.ui.container.Panel
        LoadingImage                   matlab.ui.control.Image
        Panel_23                       matlab.ui.container.Panel
        RFRImage                       matlab.ui.control.Image
        TireAnalyzerLabel              matlab.ui.control.Label
        ByMatthewDrummondStoylesLabel  matlab.ui.control.Label
        SeesourcecodeLabel             matlab.ui.control.Label
        EditField                      matlab.ui.control.EditField
        StatusPanel                    matlab.ui.container.Panel
        PrimaryStatusLabel             matlab.ui.control.Label
        SecondaryStatusLabel           matlab.ui.control.Label
        TertiaryStatusLabel            matlab.ui.control.Label
    end

    
    % ----- INFO -----
    % 1. Indexing goes: (sa, sa_dir, load, camber, pressure)
    %
    % ----------------
    
    properties (Access = private)
        % Settings
        SABounds % Slip angle bounds
        LoadBounds % Load bounds
        d_SA % Slip angle step size
        d_load % Load step size
        
        % Data files info
        n_files % Number of data files
        n_tires
        FileNames
        TireNamesRaw
        TireNames
        TireInfoRaw cell
        TireInfoMap containers.Map
        TireDataMap containers.Map % Tire data variables
        TireDataRaw cell
        TireNamesSorted cell % Tire names in order of which was found first
        UsedTireData struct % Ones actually used
        FileStartIndices
        StartIndex
        P_lb
        P_ub
        IA_lb
        IA_ub
        FZ_lb
        FZ_ub
        NFY_groups
        SA_groups
        PacejkaDataAxesHandle
        PacejkaFitAxesHandle
        LoadSensAxesDataHandle
        LoadSensAxesFitHandle
        CamberSensAxesHandle
        PressureSensAxesHandle
        DoFits
        SeparateSA
        PacejkaCoeff
        PacejkaVals
        LoadPolyCoeff
        mean_loads
        SolverExitFlags
        SA_Vals double
        Load_Vals
        LatCoeffVals
        Mz_groups
        MzPacejkaCoeff
        MzPacejkaVals
        Mz_Vals
        MzExitFlags
        MzLoadPolyCoeff
        Round
        TestSectionUpperBounds cell
        RunOptions struct = struct( ...
            'SaveData', false, ...
            'UseDiffScalingFactors', false, ...
            'XScalingFactor', 1, ...
            'YScalingFactor', 1, ...
            'CreateFits', true, ...
            'SeparateSADir', false, ...
            'SABound', 15, ...
            'SAStepSize', 0.1, ...
            'SASampleVals', 0, ...
            'PacejkaMaxIterations', 3000, ...
            'PacejkaAlgorithm', 'levenberg-marquardt', ...
            'LoadStepSize', 20, ...
            'LoadUpperBound', 1200, ...
            'LoadSampleVals', 0, ...
            'LoadSensOrder', 2 ...
            )
        PacejkaIAorPState CamberOrPressureState
        LoadSensIAorPState CamberOrPressureState
    end
    
    properties (Access = public)
        LatPressureLUTData
        LatCamberLUTData
        LatLUTData
    end
    
    properties (Constant)
        TestSectionActions containers.Map = containers.Map( ...
            {'Inc angles SA sweeps', ...
            'Pressures SA sweeps', ...
            'Main SA sweeps'}, ...
            {SASweepsAction(), ...
            SASweepsAction(), ...
            SASweepsAction()});
    end
    
    methods (Access = private)
        
        function [lb, ub] = fit_load_groups(app, start_i, end_i)
            
        end
        
        % Algorithm for finding the index where the regular SA sweeps start
        function index = find_start_index(app)
            counter = 0;
            start_again = false;
            n_data = length(app.UsedTireData.ET);
            disp(strcat('Number of points: ', num2str(n_data)))
            disp(strcat('Tire name: ', app.UsedTireData.tireid))
            
            % Iterate over all the data
            for i = 1:n_data
                
                % Check if abs value of point is less than 0.1
                if abs(app.UsedTireData.IA(i)) < 0.1
                    counter = counter + 1;
                else % If not, reset the counter
                    counter = 0;
                end
                
                % Outside of first flat zone?
                if abs(app.UsedTireData.IA(i)) > 0.1
                    disp('Exited first flat zone')
                    start_again = true;
                    counter = 0;
                end
                
                % Only if this is the 1000th point that's less than 0.1
                % AND if this is the second flat zone, not first
                if counter == 1000 && start_again
                    index = i - 1000; % Where we started counting
                    return;
                end
                
                if i == n_data
                    error('Matt error: no start index found');
                end
            end
        end
        
        function separate_data(app, x_scaling_factor, y_scaling_factor)
            
            [app.P_lb, app.P_ub] = fit_pressure_groups(app.UsedTireData.P, app.StartIndex, length(app.UsedTireData.ET));
            
            for p_counter = 1:3
                [app.IA_lb(:, p_counter), app.IA_ub(:, p_counter)] = fit_camber_groups(app.UsedTireData.IA, app.P_lb(p_counter), app.P_ub(p_counter));
                
                for ia_counter = 1:3
                    % Haven't put load grouping into its own function since the first one has to be done separately
                    
                    % First group has an extra load group, so do it separately
                    if ia_counter == 1
                        [fz_lb, fz_ub] = group_data_diff( ...
                            app.UsedTireData.FZ(app.IA_lb(1, p_counter):app.IA_ub(1, p_counter)), ...
                            6, ...
                            160, ...
                            app.IA_lb(1, p_counter) - 1);
                        %title("FZ grouping for P=" + p_counter + ", IA=0");
                        app.FZ_lb(:, 1, p_counter) = fz_lb(2:6);
                        app.FZ_ub(:, 1, p_counter) = fz_ub(2:6);
                    else
                        [app.FZ_lb(:, ia_counter, p_counter), app.FZ_ub(:, ia_counter, p_counter)] = ...
                            group_data_diff( ...
                            app.UsedTireData.FZ(app.IA_lb(ia_counter, p_counter):app.IA_ub(ia_counter, p_counter)), ...
                            5, ...
                            160, ...
                            app.IA_lb(ia_counter, p_counter) - 1);
                        %title("FZ grouping for P=" + p_counter + ", IA=" + 2*(ia_counter - 1));
                    end
                    
                    for fz_counter = 1:5
                        
                        start_i = app.FZ_lb(fz_counter, ia_counter, p_counter);
                        end_i = app.FZ_ub(fz_counter, ia_counter, p_counter);
                        
                        if p_counter == 1 && ia_counter == 1 && fz_counter == 2
                            disp(['Start i: ' num2str(start_i)]);
                            disp(['End i: ' num2str(end_i)]);
                        end
                        
                        if app.SeparateSA
                            [index_1, index_2] = fit_sa_ranges_run(app.UsedTireData.SA, start_i, end_i);
                            %if p_counter == 1 && ia_counter == 1 && fz_counter == 2
                                %disp(['start_i: ' num2str(start_i)])
                                %disp(['Index 1: ' num2str(index_1)]);
                                %disp(['Index 2: ' num2str(index_2)]);
                                %disp(['end_i: ' num2str(end_i)])
                            %end
                            
                            % NFY where SA is increasing is the first and third sections of the grouped SA data
                            nfy_inc_part1 = app.UsedTireData.NFY(start_i:index_1);
                            nfy_inc_part2 = app.UsedTireData.NFY(index_2:end_i);
                            app.NFY_groups{1, fz_counter, ia_counter, p_counter} = y_scaling_factor*[nfy_inc_part1; nfy_inc_part2];
                            
                            app.NFY_groups{2, fz_counter, ia_counter, p_counter} = y_scaling_factor*app.UsedTireData.NFY(index_1:index_2);
                            
                            sa_inc_part1 = app.UsedTireData.SA(start_i:index_1);
                            sa_inc_part2 = app.UsedTireData.SA(index_2:end_i);
                            app.SA_groups{1, fz_counter, ia_counter, p_counter} = x_scaling_factor*[sa_inc_part1; sa_inc_part2];
                            
                            app.SA_groups{2, fz_counter, ia_counter, p_counter} = x_scaling_factor*app.UsedTireData.SA(index_1:index_2);
                        else
                            app.SA_groups{fz_counter, ia_counter, p_counter} = x_scaling_factor*app.UsedTireData.SA(start_i:end_i);
                            app.NFY_groups{fz_counter, ia_counter, p_counter} = y_scaling_factor*app.UsedTireData.NFY(start_i:end_i);
                            if app.SaveFyandMzdataCheckBox.Value
                                app.Mz_groups{fz_counter, ia_counter, p_counter} = y_scaling_factor*app.UsedTireData.MZ(start_i:end_i);
                            end
                        end
                    end % End of load loop (camber-specific)
                end % End of camber group (pressure-specific)
            end % End of pressure loop (run once)
            
            disp(['Start: ' num2str(app.IA_lb(1, 1))])
            disp(['End: ' num2str(app.IA_ub(1, 1))])
        end
        
        function fit_data(app, algorithm, max_iterations, SA_bounds, delta_SA, load_bounds, delta_load, load_order)
            
            SA_vals = SA_bounds(1):delta_SA:SA_bounds(2);
            load_vals = load_bounds(1):delta_load:load_bounds(2);
            
            n_SA = length(SA_vals);
            n_load = length(load_vals);
            
            for p_counter = 1:3
                
                app.PrimaryStatusLabel.Text = strcat('Fitting pressure group ', num2str(p_counter), '/3');
                
                for ia_counter = 1:3
                    
                    app.SecondaryStatusLabel.Text = strcat('Fitting camber group ', num2str(ia_counter), '/3');
                    
                    app.mean_loads(:, ia_counter, p_counter) = zeros(1, 5);
                    for i = 1:5
                        app.mean_loads(i, ia_counter, p_counter) = -mean(app.UsedTireData.FZ(app.FZ_lb(i, ia_counter, p_counter):app.FZ_ub(i, ia_counter, p_counter)));
                    end
                    
                    % Pacejka fits
                    for fz_counter = 1:5
                        
                        app.TertiaryStatusLabel.Text = strcat('Fitting load group ', num2str(fz_counter), '/5');
                        update_ui(app);
                        
                        if app.SeparateSA
                            % Get values from inc array
                            alpha = app.SA_groups{1, fz_counter, ia_counter, p_counter};
                            mu = app.NFY_groups{1, fz_counter, ia_counter, p_counter};
                            % Fit the data and get the coefficients
                            [C, exitflag] = fit_pacejka(alpha, mu, algorithm, max_iterations);
                            app.PacejkaCoeff(:, 1, fz_counter, ia_counter, p_counter) = C;
                            app.SolverExitFlags(1, fz_counter, ia_counter, p_counter) = exitflag;
                            
                            % Get values from dec array
                            alpha = app.SA_groups{2, fz_counter, ia_counter, p_counter};
                            mu = app.NFY_groups{2, fz_counter, ia_counter, p_counter};
                            % Fit the data and get the coefficients
                            [C, exitflag] = fit_pacejka(alpha, mu, algorithm, max_iterations);
                            app.PacejkaCoeff(:, 2, fz_counter, ia_counter, p_counter) = C;
                            app.SolverExitFlags(2, fz_counter, ia_counter, p_counter) = exitflag;
                            
                            % Take sample points of each pacejka function
                            app.PacejkaVals(:, 1, fz_counter, ia_counter, p_counter) = pacejka(app.PacejkaCoeff(:, 1, fz_counter, ia_counter, p_counter), deg2rad(SA_vals));
                            app.PacejkaVals(:, 2, fz_counter, ia_counter, p_counter) = pacejka(app.PacejkaCoeff(:, 2, fz_counter, ia_counter, p_counter), deg2rad(SA_vals));
                            
                            if p_counter == 1 && ia_counter == 1 && fz_counter == 2
                                disp('Inc: ')
                                app.PacejkaCoeff(:, 1, 2, 1, 1)
                                disp('Dec: ')
                                app.PacejkaCoeff(:, 2, 2, 1, 1)
                            end
                        else
                            alpha = app.SA_groups{fz_counter, ia_counter, p_counter};
                            mu = app.NFY_groups{fz_counter, ia_counter, p_counter};
                            % Fit the data and get the coefficients
                            [C, exitflag] = fit_pacejka(alpha, mu, algorithm, max_iterations);
                            app.PacejkaCoeff(:, fz_counter, ia_counter, p_counter) = C;
                            app.SolverExitFlags(fz_counter, ia_counter, p_counter) = exitflag;
                            
                            % Sample points on the fitted Pacejka curve
                            app.PacejkaVals(:, fz_counter, ia_counter, p_counter) = pacejka(app.PacejkaCoeff(:, fz_counter, ia_counter, p_counter), deg2rad(SA_vals));
                            
                            if app.SaveFyandMzdataCheckBox.Value
                                mz = app.Mz_groups{fz_counter, ia_counter, p_counter};
                                [C, exitflag] = fit_pacejka(alpha, mz, algorithm, max_iterations);
                                app.MzPacejkaCoeff(:, fz_counter, ia_counter, p_counter) = C;
                                app.MzExitFlags(fz_counter, ia_counter, p_counter) = exitflag;
                                
                                app.MzPacejkaVals(:, fz_counter, ia_counter, p_counter) = pacejka(app.MzPacejkaCoeff(:, fz_counter, ia_counter, p_counter), deg2rad(SA_vals));
                            end
                        end
                        
                        %if p_counter == 1 && ia_counter == 1 && fz_counter == 
                        
                    end % End of load loop (camber-specific)
                    
                    % Fit loads
                    for sa_counter = 1:n_SA
                        if app.SeparateSA
                            app.LoadPolyCoeff(:, sa_counter, 1, ia_counter, p_counter) = ...
                                polyfit(app.mean_loads(:, ia_counter, p_counter), ...
                                app.PacejkaVals(sa_counter, 1, :, ia_counter, p_counter), load_order);
                            app.LoadPolyCoeff(:, sa_counter, 2, ia_counter, p_counter) = ...
                                polyfit(app.mean_loads(:, ia_counter, p_counter), ...
                                app.PacejkaVals(sa_counter, 2, :, ia_counter, p_counter), load_order);
                            
                            % Populate the spaces in between the load data given with values on the polynomial
                            app.LatCoeffVals(sa_counter, 1, :, ia_counter, p_counter) = ...
                                polyval(app.LoadPolyCoeff(:, sa_counter, 1, ia_counter, p_counter), load_vals);
                            app.LatCoeffVals(sa_counter, 2, :, ia_counter, p_counter) = ...
                                polyval(app.LoadPolyCoeff(:, sa_counter, 2, ia_counter, p_counter), load_vals);
                        else
                            app.LoadPolyCoeff(:, sa_counter, ia_counter, p_counter) = ...
                                polyfit(app.mean_loads(:, ia_counter, p_counter), ...
                                app.PacejkaVals(sa_counter, :, ia_counter, p_counter), load_order);
                            % Populate the spaces in between the load data given with values on the polynomial
                            app.LatCoeffVals(sa_counter, :, ia_counter, p_counter) = ...
                                polyval(app.LoadPolyCoeff(:, sa_counter, ia_counter, p_counter), load_vals);
                            
                            if app.SaveFyandMzdataCheckBox.Value
                                app.MzLoadPolyCoeff(:, sa_counter, ia_counter, p_counter) = ...
                                    polyfit(app.mean_loads(:, ia_counter, p_counter), ...
                                    app.MzPacejkaVals(sa_counter, :, ia_counter, p_counter), load_order);
                                app.Mz_Vals(sa_counter, :, ia_counter, p_counter) = ...
                                    polyval(app.MzLoadPolyCoeff(:, sa_counter, ia_counter, p_counter), load_vals);
                            end
                        end
                    end
                    
                end % End of camber group (pressure-specific)
            end % End of pressure loop (run once)
        end
        
        function update_ui(~)
            % For some reason, this works
            pause(0.000001);
        end
        
        function update_tire_details(app, tire_id)
            round_num = app.getRoundNumber(tire_id);
            
            app.RoundDropDown.Value = num2str(round_num);
            
            manu = extractBetween(tire_id, '', ' ');
            
            app.ManufacturerDropDown.Value = manu{1};
        end
        
        function round = getRoundNumber(app, tire_id)
            tire_info = app.TireInfoMap(tire_id);
            filename = tire_info.file_name;
            cell_val = extractBetween(filename, 2, 5);
            num = cell_val{1};
            switch num
                case '1051'
                    round = 1;
                case '1052'
                    round = 2;
                case '1175'
                    round = 3;
                case '1320'
                    round = 4;
                case '1464'
                    round = 5;
                case '1654'
                    round = 6;
                case '1706'
                    round = 7;
                case '1965'
                    round = 8;
            end
        end
        
        function addTestSectionAction(app, test_section_name, callback)
            app.TestSectionActions(test_section_name) = callback;
        end
        
        function SASweepsAction(app, relevant_data)
            
        end
        
        function switch_pacejka_IAorPState(app, state)
            switch state
                case CamberOrPressureState.Camber
                    app.PacejkaBothIADropDown.Visible = false;
                    app.PacejkaBothIADropDownLabel.Visible = false;
                    app.PacejkaBothPDropDown.Visible = false;
                    app.PacejkaBothPDropDownLabel.Visible = false;
                    app.PacejkaVaryDropDown.Visible = true;
                    app.PacejkaVaryDropDownLabel.Visible = true;
                    app.PacejkaOnlyPDropDown.Visible = false;
                    app.PacejkaOnlyPDropDownLabel.Visible = false;
                    app.PacejkaOnlyIADropDown.Visible = true;
                    app.PacejkaOnlyIADropDownLabel.Visible = true;
                case CamberOrPressureState.Pressure
                    app.PacejkaBothIADropDown.Visible = false;
                    app.PacejkaBothIADropDownLabel.Visible = false;
                    app.PacejkaBothPDropDown.Visible = false;
                    app.PacejkaBothPDropDownLabel.Visible = false;
                    app.PacejkaVaryDropDown.Visible = true;
                    app.PacejkaVaryDropDownLabel.Visible = true;
                    app.PacejkaOnlyIADropDown.Visible = false;
                    app.PacejkaOnlyIADropDownLabel.Visible = false;
                    app.PacejkaOnlyPDropDown.Visible = true;
                    app.PacejkaOnlyPDropDownLabel.Visible = true;
                case CamberOrPressureState.Both
                    app.PacejkaVaryDropDown.Enable = 'off';
                    app.PacejkaVaryDropDownLabel.Enable = 'off';
                    app.PacejkaOnlyPDropDown.Visible = false;
                    app.PacejkaOnlyPDropDownLabel.Visible = false;
                    app.PacejkaOnlyIADropDown.Visible = false;
                    app.PacejkaOnlyIADropDownLabel.Visible = false;
                    app.PacejkaBothIADropDown.Visible = true;
                    app.PacejkaBothIADropDownLabel.Visible = true;
                    app.PacejkaBothPDropDown.Visible = true;
                    app.PacejkaBothPDropDownLabel.Visible = true;
            end
            app.PacejkaIAorPState = state;
        end
        
        function switch_loadsens_IAorPState(app, state)
            switch state
                case CamberOrPressureState.Camber
                    app.FZPDropDown.Enable = 'off';
                    app.FZIADropDown.Enable = 'on';
                case CamberOrPressureState.Pressure
                    app.FZPDropDown.Enable = 'on';
                    app.FZIADropDown.Enable = 'off';
                case CamberOrPressureState.Both
                    app.FZVaryDropDown.Enable = 'off';
                    app.FZPDropDown.Enable = 'on';
                    app.FZIADropDown.Enable = 'on';
            end
            app.LoadSensIAorPState = state;
        end
        
        function update_pacejka_axes(app)
            delete(app.PacejkaDataAxesHandle);
            delete(app.PacejkaFitAxesHandle);
            
            switch app.PacejkaIAorPState
                case CamberOrPressureState.Camber
                    fz_index = get_fz_index_from_string_pacejka(app, app.PacejkaFZDropDown.Value);
                    ia_index = get_ia_index_from_string_pacejka(app, app.PacejkaOnlyIADropDown.Value);
                    x_data = app.LatCamberLUTData.sa_data{fz_index, ia_index};
                    y_data = app.LatCamberLUTData.nfy_data{fz_index, ia_index};
                    if app.RunOptions.CreateFits
                        pacejka_x = app.RunOptions.SASampleVals;
                        %if app.SeparateSA
                        %    pacejka_y = pacejka(app.PacejkaCoeff(:, SAdir_index, fz_index, ia_index, p_index), deg2rad(pacejka_x));
                        %else
                        %    pacejka_y = pacejka(app.PacejkaCoeff(:, fz_index, ia_index, p_index), deg2rad(pacejka_x));
                        %end
                        pacejka_y = app.LatCamberLUTData.nfy_sample_points(:, fz_index, ia_index);
                        app.PacejkaFitAxesHandle = plot(app.PacejkaFitAxes, pacejka_x, pacejka_y, 'red');
                        if app.LatCamberLUTData.nfy_exitflags(fz_index, ia_index) == 0
                            app.PacejkaFitNotCompletedLabel.Visible = 'on';
                        else
                            app.PacejkaFitNotCompletedLabel.Visible = 'off';
                        end
                    end
                case CamberOrPressureState.Pressure
                    disp('P OPTIONS: ')
                    disp(app.LatPressureLUTData.p_options)
                    disp('ITEMS: ')
                    disp(app.PacejkaOnlyPDropDown.Items)
                    update_ui(app);
                    disp('VALUE:')
                    disp(app.PacejkaOnlyPDropDown.Value)
                    fz_index = get_fz_index_from_string_pacejka(app, app.PacejkaFZDropDown.Value);
                    p_index = get_p_index_from_string_pacejka(app, app.PacejkaOnlyPDropDown.Value);
                    x_data = app.LatPressureLUTData.sa_data{fz_index, p_index};
                    y_data = app.LatPressureLUTData.nfy_data{fz_index, p_index};
                    if app.RunOptions.CreateFits
                        pacejka_x = app.RunOptions.SASampleVals;
                        %if app.SeparateSA
                        %    pacejka_y = pacejka(app.PacejkaCoeff(:, SAdir_index, fz_index, ia_index, p_index), deg2rad(pacejka_x));
                        %else
                        %    pacejka_y = pacejka(app.PacejkaCoeff(:, fz_index, ia_index, p_index), deg2rad(pacejka_x));
                        %end
                        pacejka_y = app.LatPressureLUTData.nfy_sample_points(:, fz_index, p_index);
                        app.PacejkaFitAxesHandle = plot(app.PacejkaFitAxes, pacejka_x, pacejka_y, 'red');
                        app.PacejkaFitNotCompletedLabel.Visible = app.LatPressureLUTData.nfy_exitflags(fz_index, p_index) == 0;
                    end
                case CamberOrPressureState.Both
                    fz_index = get_fz_index_from_string_pacejka(app, app.PacejkaFZDropDown.Value);
                    ia_index = get_ia_index_from_string_pacejka(app, app.PacejkaBothIADropDown.Value);
                    p_index = get_p_index_from_string_pacejka(app, app.PacejkaBothPDropDown.Value);
                    %disp(['fz: ' num2str(fz_index)])
                    %disp(['ia: ' num2str(ia_index)])
                    %disp(['p: ' num2str(p_index)])
                    x_data = app.LatLUTData.sa_data{fz_index, ia_index, p_index};
                    y_data = app.LatLUTData.nfy_data{fz_index, ia_index, p_index};
                    if app.RunOptions.CreateFits
                        pacejka_x = app.RunOptions.SASampleVals;
                        %if app.SeparateSA
                        %    pacejka_y = pacejka(app.PacejkaCoeff(:, SAdir_index, fz_index, ia_index, p_index), deg2rad(pacejka_x));
                        %else
                        %    pacejka_y = pacejka(app.PacejkaCoeff(:, fz_index, ia_index, p_index), deg2rad(pacejka_x));
                        %end
                        pacejka_y = app.LatLUTData.nfy_sample_points(:, fz_index, ia_index, p_index);
                        app.PacejkaFitAxesHandle = plot(app.PacejkaFitAxes, pacejka_x, pacejka_y, 'red');
                        app.PacejkaFitNotCompletedLabel.Visible = app.LatLUTData.nfy_exitflags(fz_index, ia_index, p_index) == 0;
                    end
            end
            
            %SAdir_index = get_SAdir_index_from_string(app.PacejkaSAdirDropDown.Value);
            
            %if app.SeparateSA
            %    x_data = app.SA_groups{SAdir_index, fz_index, ia_index, p_index};
            %    y_data = app.NFY_groups{SAdir_index, fz_index, ia_index, p_index};
            %else
            %    x_data = app.SA_groups{fz_index, ia_index, p_index};
            %    y_data = app.NFY_groups{fz_index, ia_index, p_index};
            %end
            
            app.PacejkaDataAxesHandle = scatter(app.PacejkaFitAxes, x_data, y_data, 5, 'blue');
            axis(app.PacejkaFitAxes, [-15 15 -3.2 3.2]);
            
            %if app.SeparateSA
            %    app.PacejkaFitNotCompletedLabel.Visible = app.SolverExitFlags(SAdir_index, fz_index, ia_index, p_index) == 0;
            %else
            %    app.PacejkaFitNotCompletedLabel.Visible = app.SolverExitFlags(fz_index, ia_index, p_index) == 0;
            %end
        end
        
        function update_loadsens_axes_auto(app)
            sa_index = find(app.RunOptions.SASampleVals == app.SASlider.Value);
            if isempty(sa_index)
                error(['Matt error: no sa_index found. Slider value: ' num2str(app.SASlider.Value)])
            end
            update_loadsens_axes_given_sa_index(app, sa_index);
        end
        
        function update_loadsens_axes_w_round(app, new_val)
            [~, ind] = min(abs(app.RunOptions.SASampleVals - new_val));
            rounded_val = app.RunOptions.SASampleVals(ind);
            if rounded_val ~= app.SASlider.Value
                update_loadsens_axes_given_sa_index(app, ind);
                app.SASlider.Value = rounded_val;
            end
        end
        
        function update_loadsens_axes_given_sa_index(app, sa_index)
            
            delete(app.LoadSensAxesDataHandle);
            delete(app.LoadSensAxesFitHandle);
            
            %if app.SeparateSA
            %    SAdir_index = get_SAdir_index_from_string(app.FZSAdirDropDown.Value);
            %else
            %    SAdir_index = 0;
            %end
            
            switch app.LoadSensIAorPState
                case CamberOrPressureState.Camber
                    ia_index = get_ia_index_from_string_loadsens(app, app.FZIADropDown.Value);
            
                    % Plot data points
                    actual_load_vals = app.LatCamberLUTData.mean_loads(:, ia_index);
                    %if SAdir_index == 0
                    %    actual_coeff = app.PacejkaVals(sa_index, :, ia_index, p_index);
                    %else
                    %    actual_coeff = app.PacejkaVals(sa_index, SAdir_index, :, ia_index, p_index);
                    %end
                    actual_coeff = app.LatCamberLUTData.nfy_sample_points(sa_index, :, ia_index);
                    %size(app.LatCamberLUTData.mean_loads(:, ia_index))
                    %size(app.LatCamberLUTData.nfy_sample_points(sa_index, :, ia_index))
                    %disp(sa_index)
                    app.LoadSensAxesDataHandle = scatter(app.FZAxes, actual_load_vals, actual_coeff, 'magenta');
                    
                    % Plot fit line
                    %if SAdir_index == 0
                    %    yvals = app.LatCoeffVals(sa_index, :, ia_index, p_index);
                    %else
                    %    yvals = app.LatCoeffVals(sa_index, SAdir_index, :, ia_index, p_index);
                    %end
                    xvals = -app.RunOptions.LoadSampleVals;
                    yvals = app.LatCamberLUTData.nfy_vals(sa_index, :, ia_index);
                    
                    app.LoadSensAxesFitHandle = line(app.FZAxes, ...
                        'XData', ...
                        xvals, ...
                        'YData', ...
                        reshape(yvals, size(xvals)), ...
                        'Color', 'cyan');
                    drawnow
                case CamberOrPressureState.Pressure
                    p_index = get_p_index_from_string_loadsens(app, app.FZPDropDown.Value);
                    
                    % Plot data points
                    actual_load_vals = app.LatPressureLUTData.mean_loads(:, p_index);
                    actual_coeff = app.LatPressureLUTData.nfy_sample_points(sa_index, :, p_index);
                    
                    app.LoadSensAxesDataHandle = scatter(app.FZAxes, actual_load_vals, actual_coeff, 'magenta');
                    
                    % Plot fit line
                    xvals = -app.RunOptions.LoadSampleVals;
                    yvals = app.LatPressureLUTData.nfy_vals(sa_index, :, p_index);
                    
                    app.LoadSensAxesFitHandle = line(app.FZAxes, ...
                        'XData', ...
                        xvals, ...
                        'YData', ...
                        yvals, ...
                        'Color', 'cyan');
                    drawnow
                case CamberOrPressureState.Both
                    ia_index = get_ia_index_from_string_loadsens(app, app.FZIADropDown.Value);
                    p_index = get_p_index_from_string_loadsens(app, app.FZPDropDown.Value);
                    
                    % Plot data points
                    actual_load_vals = app.LatLUTData.mean_loads(:, ia_index, p_index);
                    actual_coeff = app.LatLUTData.nfy_sample_points(sa_index, :, ia_index, p_index);
                    
                    app.LoadSensAxesDataHandle = scatter(app.FZAxes, actual_load_vals, actual_coeff, 'magenta');
                    
                    % Plot fit line
                    xvals = -app.RunOptions.LoadSampleVals;
                    yvals = app.LatLUTData.nfy_vals(sa_index, :, ia_index, p_index);
                    app.LoadSensAxesFitHandle = line(app.FZAxes, ...
                        'XData', ...
                        xvals, ...
                        'YData', ...
                        yvals, ...
                        'Color', 'cyan');
                    drawnow
            end
        end
        
        function struct_name = get_structname_from_pacejka_state(app)
            switch app.PacejkaIAorPState
                case CamberOrPressureState.Camber
                    struct_name = 'LatCamberLUTData';
                case CamberOrPressureState.Pressure
                    struct_name = 'LatPressureLUTData';
                case CamberOrPressureState.Both
                    struct_name = 'LatLUTData';
            end
        end
        
        function struct_name = get_structname_from_loadsens_state(app)
            switch app.LoadSensIAorPState
                case CamberOrPressureState.Camber
                    struct_name = 'LatCamberLUTData';
                case CamberOrPressureState.Pressure
                    struct_name = 'LatPressureLUTData';
                case CamberOrPressureState.Both
                    struct_name = 'LatLUTData';
            end
        end
        
        function ia_index = get_ia_index_from_string_pacejka(app, string_value)
            struct_name = get_structname_from_pacejka_state(app);
            value = str2double(string_value);
            ia_index = find(app.(struct_name).ia_options == value);
        end
        
        function fz_index = get_fz_index_from_string_pacejka(app, string_value)
            struct_name = get_structname_from_pacejka_state(app);
            value = str2double(string_value);
            fz_index = find(app.(struct_name).fz_options == value);
        end
        
        function p_index = get_p_index_from_string_pacejka(app, string_value)
            struct_name = get_structname_from_pacejka_state(app);
            value = str2double(string_value);
            p_index = find(app.(struct_name).p_options == value);
        end
        
        function ia_index = get_ia_index_from_string_loadsens(app, string_value)
            struct_name = get_structname_from_loadsens_state(app);
            value = str2double(string_value);
            ia_index = find(app.(struct_name).ia_options == value);
        end
        
        function p_index = get_p_index_from_string_loadsens(app, string_value)
            struct_name = get_structname_from_loadsens_state(app);
            value = str2double(string_value);
            p_index = find(app.(struct_name).p_options == value);
        end
        
        function [SAdir_index] = get_SAdir_index_from_string(string)
            switch string
                case '+'
                    SAdir_index = 1;
                case '-'
                    SAdir_index = 2;
            end
        end
        
        function update_pacejka_dropdowns(app)
            switch app.PacejkaIAorPState
                case CamberOrPressureState.Camber
                    app.PacejkaOnlyIADropDown.Items = string(num2cell(app.LatCamberLUTData.ia_options));
                    app.PacejkaFZDropDown.Items = string(num2cell(app.LatCamberLUTData.fz_options));
                case CamberOrPressureState.Pressure
                    app.PacejkaOnlyPDropDown.Items = string(num2cell(app.LatPressureLUTData.p_options));
                    app.PacejkaFZDropDown.Items = string(num2cell(app.LatPressureLUTData.fz_options));
                case CamberOrPressureState.Both
                    app.PacejkaBothIADropDown.Items = string(num2cell(app.LatLUTData.ia_options));
                    app.PacejkaBothPDropDown.Items = string(num2cell(app.LatLUTData.p_options));
                    app.PacejkaFZDropDown.Items = string(num2cell(app.LatLUTData.fz_options));
            end
        end
        
        function update_pacejka_IAorPState_from_dropdown(app)
            switch app.PacejkaVaryDropDown.Value
                case 'Inc. angle'
                    switch_pacejka_IAorPState(app, CamberOrPressureState.Camber);
                case 'Pressure'
                    switch_pacejka_IAorPState(app, CamberOrPressureState.Pressure);
            end
        end
        
        function update_loadsens_IAorPState_from_dropdown(app)
            switch app.FZVaryDropDown.Value
                case 'Inc. angle'
                    switch_loadsens_IAorPState(app, CamberOrPressureState.Camber);
                case 'Pressure'
                    switch_loadsens_IAorPState(app, CamberOrPressureState.Pressure);
            end
        end
        
        function update_loadsens_dropdowns(app)
            switch app.LoadSensIAorPState
                case CamberOrPressureState.Camber
                    app.FZIADropDown.Items = string(num2cell(app.LatCamberLUTData.ia_options));
                case CamberOrPressureState.Pressure
                    app.FZPDropDown.Items = string(num2cell(app.LatPressureLUTData.p_options));
                case CamberOrPressureState.Both
                    app.FZIADropDown.Items = string(num2cell(app.LatLUTData.ia_options));
                    app.FZPDropDown.Items = string(num2cell(app.LatLUTData.p_options));
            end
        end
        
        function suggest_load_bound(app)
            tire_name = app.TireDropDown.Value;
            max_load = max(-app.TireDataMap(tire_name).FZ);
            max_rounded_load = ceil(max_load/100) * 100;
            app.LoadUBEditField.Value = max_rounded_load;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.PrimaryStatusLabel.Text = 'App started';
            app.PacejkaFitAxes.Visible = false;
            app.FZAxes.Visible = false;
            app.PacejkaFitNotCompletedLabel.Visible = false;
            app.RunOptionsPanel.Enable = 'off';
            update_ui(app);
            
            
            %app.SourceCodeLink = uihyperlink(app.TitlePanel);
            %app.SourceCodeLink.URL = 'https://github.com/yafta98/TireAnalyzer';
            
            % Empty map which wil be used to map each set of data to the tire name
            app.TireDataMap = containers.Map('KeyType', 'char', 'ValueType', 'any');
            
            % Load files in tire data directory
            folder_contents = what('tire_data\');
            % Only list .mat files
            mat_files = natsortfiles(folder_contents.mat);
            app.FileNames = mat_files;
            % Number of files
            app.n_files = length(mat_files);
            
            % Load each tire's name into the array
            % At some point, we can parse the tire info from the string
            app.PrimaryStatusLabel.Text = 'Loading data from files into workspace...';
            update_ui(app);
            app.TireNamesRaw = cell(app.n_files);
            tire_names = cell(1);
            found_index = 0;
            for i = 1:app.n_files
                app.SecondaryStatusLabel.Text = "Loading file " + i + "/" + app.n_files;
                %update_ui(app);
                %disp("Loading file " + i + "/" + app.n_files)
                
                %disp('--------- New file --------')
                
                file_path = strcat('tire_data/', mat_files{i});
                
                % Load the tire info and data
                tire_data = load(file_path);
                tire_id = tire_data.tireid;
                
                %disp(strcat('i: ', num2str(i)))
                %disp(strcat('Tire id: ', tire_id))
                
                app.TireDataRaw{i} = tire_data;
                
                app.TireInfoRaw{i} = struct('file_name', mat_files{i}, 'tire_id', tire_data.tireid);
                
%                 if app.TireData.isKey(tire_id) % If it's another file for an existing tire
%                     %disp('Another file for this tire')
%                     
%                     if isempty(app.TireDataMap(tire_id))
%                         %disp(strcat('Setting data at index: ', num2str(i)))
%                         %disp(strcat('found_index is: ', num2str(found_index)))
%                         tire_data.filename = mat_files{i};
%                         app.TireDataMap(tire_id) = tire_data;
%                     end
%                 else % If this is the first file for this tire
%                     if isempty(app.TireData)
%                         tire_names{1} = tire_id;
%                     else
%                         tire_names{length(tire_names) + 1} = tire_id;
%                     end
%                     %disp(class(tire_id))
%                     %tire_id
%                     found_index = found_index + 1;
%                     app.FileStartIndices(found_index) = i;
%                     %disp('First file for this tire')
%                     app.TireDataMap(tire_id) = '';
%                end
            end
            app.PrimaryStatusLabel.Text = 'Done loading files';
            app.SecondaryStatusLabel.Text = '';
            
            % Concatenate the data from different files but same tire
            app.PrimaryStatusLabel.Text = 'Concantenating data...';
%             field_names = fieldnames(app.TireDataRaw{1}); % Get the field names of the data from one of the files
%             for i = 1:numel(field_names)
%                 app.SecondaryStatusLabel.Text = "Concatenating field: " + field_names{i};
%                 update_ui(app);
%                 for j = 1:length(app.TireDataRaw)
%                     if isfield(app.TireData, field_names{i}) ...
%                             && ~strcmp(field_names{i}, 'channel') ...
%                             && ~strcmp(field_names{i}, 'source') ...
%                             && ~strcmp(field_names{i}, 'testid') ...
%                             && ~strcmp(field_names{i}, 'tireid')
%                     app.TireData.(field_names{i}) = [app.TireData.(field_names{i}); app.TireData{j}.(field_names{i})];
%                     else
%                         app.TireDataMap(1).(field_names{i}) = app.TireData{j}.(field_names{i});
%                     end
%                 end
%             end
            for i = 1:length(app.TireDataRaw) % Iterate over tire data entries (one for each file)
                tire_name = app.TireDataRaw{i}.tireid;
                
                if contains(tire_name, '--')
                    tire_name = extractBetween(tire_name, '', ' --');
                    tire_name = tire_name{1};
                end
                
                field_names = fieldnames(app.TireDataRaw{i});
                
                is_new = ~ismember(tire_name, app.TireDataMap.keys);
                
                if is_new
                    data_struct = struct();
                else
                    original_data = app.TireDataMap(tire_name);
                end
                
                new_data = app.TireDataRaw{i};
                
                for j = 1:numel(field_names) % Iterate over field names
                    field_name_cell = field_names(j);
                    field_name = field_name_cell{1};
                    if ~strcmp(field_names{j}, 'channel') ...
                            && ~strcmp(field_names{j}, 'source') ...
                            && ~strcmp(field_names{j}, 'testid') ...
                            && ~strcmp(field_names{j}, 'tireid') % Don't concatenate these fields
                    
                        if is_new % If this is the first file for this tire
                            data_struct.(field_name) = new_data.(field_name);
                        else % If this is a tire that already came up
                            original_data.(field_name) = ...
                                [original_data.(field_name); new_data.(field_name)];
                        end
                    end
                end
                % Less than optimal solution since this has to be checked
                % again... but it's preferred over iterating over field
                % names first and then tires
                if is_new
                    app.TireDataMap(tire_name) = data_struct;
                    app.TireInfoMap(tire_name) = app.TireInfoRaw{i};
                    app.TireNamesSorted{end + 1} = tire_name;
                else
                    app.TireDataMap(tire_name) = original_data;
                end
            end
            
            app.n_tires = size(app.TireDataMap, 1);
            app.TireNames = app.TireDataMap.keys; % Not sure if necessary
            
            app.TireDropDown.Items = app.TireNamesSorted;
            
            update_tire_details(app, app.TireDropDown.Value);
            
            suggest_load_bound(app);
            
            app.LoadingImage.Visible = false;
            app.PrimaryStatusLabel.Text = 'Finished loading data. Ready to run.';
            update_ui(app);
            
            disableDefaultInteractivity(app.PacejkaFitAxes);
            disableDefaultInteractivity(app.FZAxes);
            
            app.PacejkaFitAxes.Toolbar.Visible = false;
            app.FZAxes.Toolbar.Visible = false;
            
            app.RunOptionsPanel.Enable = 'on';
        end

        % Button pushed function: RunButton
        function runButtonPushed(app, event)
            app.PrimaryStatusLabel.Text = 'Separating data...';
            app.LoadingImage.Visible = true;
            update_ui(app);
            % Disable all relevant ui elements while loading
            %app.RunOptionsPanel.Enable = 'off';
            %app.PlotsPanel.Enable = 'off';
            
            tire_name = app.TireDropDown.Value;
            app.Round = app.getRoundNumber(tire_name);
            
            app.UsedTireData = app.TireDataMap(tire_name);
            %length(app.UsedTireData.ET)
%             if app.SeparateSA
%                 enable_str = 'on';
%             else
%                 enable_str = 'off';
% %             end
%             app.PacejkaSAdirDropDown.Enable = enable_str;
%             app.FZSAdirDropDown.Enable = enable_str;
            app.PacejkaSAdirDropDown.Enable = 'off';
            app.FZSAdirDropDown.Enable = 'off';
            
            app.RunOptions.SaveData = app.SaveFyandMzdataCheckBox.Value;
            app.RunOptions.UseDiffScalingFactors = app.UsedifferentXandYscalingfactorsCheckBox.Value;
            app.RunOptions.XScalingFactor = app.XscalingfactorEditField.Value;
            if app.UsedifferentXandYscalingfactorsCheckBox.Value
                app.RunOptions.YScalingFactor = app.YscalingfactorEditField.Value;
            else
                app.RunOptions.YScalingFactor = app.RunOptions.XScalingFactor;
            end
            app.RunOptions.CreateFits = app.CreatefitsCheckBox.Value;
            app.RunOptions.SeparateSADir = app.SeparateSACheckBox.Value;
            app.RunOptions.SABound = app.SABoundEditField.Value;
            app.RunOptions.SAStepSize = app.SAStepSizeEditField.Value;
            app.RunOptions.SASampleVals = -app.RunOptions.SABound:app.RunOptions.SAStepSize:app.RunOptions.SABound;
            app.RunOptions.PacejkaMaxIterations = app.PacejkafitmaxiterationsEditField.Value;
            app.RunOptions.PacejkaAlgorithm = app.PacejkaalgorithmDropDown.Value;
            app.RunOptions.LoadStepSize = app.LoadStepSizeEditField.Value;
            app.RunOptions.LoadUpperBound = app.LoadUBEditField.Value;
            app.RunOptions.LoadSampleVals = -app.RunOptions.LoadUpperBound:app.RunOptions.LoadStepSize:0;
            app.RunOptions.LoadSensOrder = app.LoadSensitivityOrderSpinner.Value;
            
            % Testing test format specification
            testFormats = TestFormatSpec.getTestFormats();
            n_rounds = length(testFormats);
            for i = 1:n_rounds
                n_test_sections = length(testFormats(i).Tests);
                start_index = 0;
                if testFormats(i).RoundNum == app.Round
                    disp(['Round: ' num2str(testFormats(i).RoundNum)])
                    n_sections_listed = 0;
                    for j = 1:n_test_sections
                        n_sections_listed = n_sections_listed + 1;
                        test = testFormats(i).Tests(j);
                        
                        app.SecondaryStatusLabel.Text = ['Currently on: ' test.Name];
                        
                        app.TertiaryStatusLabel.Text = 'Finding upper bound...';
                        update_ui(app);
                        
                        % Get the UB
                        ub = test.getUpperBound(app.UsedTireData, start_index, []);
                        
                        app.TertiaryStatusLabel.Text = 'Doing stuff with data...';
                        update_ui(app);
                        
                        % Don't care about return struct bc the top level tests access the app directly
                        test.doStuffWithData(app, app.UsedTireData, start_index + 1, ub, [], app.RunOptions);
                        start_index = ub;
                        app.TestSectionUpperBounds{j} = ub;
                        %disp(['Test: ' test.Name ', UB: ' num2str(ub)])
                        %sections_list(n_sections_listed) = test;
                        if isa(test, 'TestSectionNested')
                            nested_tests = test.NestedTests;
                            for k = 1:length(nested_tests)
                                n_sections_listed = n_sections_listed + 1;
                                %sections_list(n_sections_listed) = nested_tests(i);
                            end
                        end
                    end
                    break;
                end
            end
            
            %disp('sa_data:')
            %disp(app.LatCamberLUTData.sa_data)
            
            if isempty(app.UsedTireData)
                error('Matt error: tire not found');
            end
            
            app.PacejkaClickRunLabel.Visible = false;
            app.FZClickRunLabel.Visible = false;
            update_ui(app);
            
            if ~isempty(app.LatLUTData)
                disp('Setting state to both')
                switch_pacejka_IAorPState(app, CamberOrPressureState.Both);
                switch_loadsens_IAorPState(app, CamberOrPressureState.Both);
            else
                update_pacejka_IAorPState_from_dropdown(app);
                update_loadsens_IAorPState_from_dropdown(app);
            end
            
            update_pacejka_dropdowns(app);
            update_loadsens_dropdowns(app);
            
            app.PrimaryStatusLabel.Text = 'Plotting Pacejka fits...';
            app.SecondaryStatusLabel.Text = '';
            app.TertiaryStatusLabel.Text = '';
            update_ui(app);
            
            hold(app.PacejkaFitAxes, "on");
            plot(app.PacejkaFitAxes, [0 0], app.RunOptions.YScalingFactor*[-3.2 3.2], 'black');
            plot(app.PacejkaFitAxes, app.RunOptions.XScalingFactor*[-15 15], [0 0], 'black');
            
            update_pacejka_axes(app);
            update_loadsens_axes_auto(app);
            
            app.PacejkaFitAxes.Visible = true;
            app.FZAxes.Visible = true;
            
            %app.PrimaryStatusLabel.Text = 'Plotting load sensitivity...';
            update_ui(app);
            
            if app.PacejkaIAorPState == CamberOrPressureState.Both
                n_success = sum(ismember(app.LatLUTData.nfy_exitflags, [1 2 3]), 'all');
                n_total_fits = numel(app.LatLUTData.nfy_exitflags);
            else
                n_success = sum(ismember(app.LatCamberLUTData.nfy_exitflags, [1 2 3]), 'all') + ...
                    sum(ismember(app.LatPressureLUTData.nfy_exitflags, [1 2 3]), 'all');
                n_total_fits = numel(app.LatCamberLUTData.nfy_exitflags) + numel(app.LatPressureLUTData.nfy_exitflags);
            end
            
            app.PrimaryStatusLabel.Text = ['Finished with ' num2str(n_success) '/' num2str(n_total_fits) ' successful Pacejka fits'];
            % Re-enable all relevant ui elements
            app.RunOptionsPanel.Enable = 'on';
            app.PlotsPanel.Enable = 'on';
            app.LoadingImage.Visible = false;
            update_ui(app);
            
            if app.SaveFyandMzdataCheckBox.Value
                sa_x = deg2rad(app.RunOptions.SASampleVals);
                load_y = app.RunOptions.LoadSampleVals;
                if app.PacejkaIAorPState == CamberOrPressureState.Both
                    lat_coeff = app.LatLUTData.nfy_vals(:, :, 1, 3);
                    aligning_torque = app.LatLUTData.mz_vals(:, :, 1, 3);
                else
                    lat_coeff = app.LatCamberLUTData.nfy_vals(:, :, 1);
                    aligning_torque = app.LatCamberLUTData.mz_vals(:, :, 1);
                end
                
                %load_y = -flip(load_y);
                %lat_coeff = -fliplr(lat_coeff);
                %aligning_torque = -fliplr(aligning_torque);
                lat_coeff = -lat_coeff;
                
                save('lateral_data_lut.mat', 'sa_x', 'load_y', 'lat_coeff', 'aligning_torque');
            end
        end

        % Callback function
        function pacejkaSAdirDropDownValueChanged(app, event)
            update_pacejka_axes(app);
        end

        % Value changed function: PacejkaFZDropDown
        function pacejkaFZDropDownValueChanged(app, event)
            update_pacejka_axes(app);
        end

        % Callback function
        function pacejkaIADropDownValueChanged(app, event)
            update_pacejka_axes(app);
        end

        % Value changed function: PacejkaBothPDropDown
        function pacejkaBothPDropDownValueChanged(app, event)
            disp('Changed P drop down')
            update_pacejka_axes(app);
        end

        % Selection changed function: ButtonGroup
        function tireSelectionMethodChanged(app, event)
            useDropDown = app.ButtonGroup.SelectedObject == app.SelectfromdropdownButton;
            app.TireDropDown.Enable = useDropDown;
            app.RoundDropDown.Enable = ~useDropDown;
            app.ManufacturerDropDown.Enable = ~useDropDown;
            app.OuterdiameterDropDown.Enable = ~useDropDown;
            app.TirewidthDropDown.Enable = ~useDropDown;
            app.RimouterdiameterDropDown.Enable = ~useDropDown;
        end

        % Value changed function: 
        % UsedifferentXandYscalingfactorsCheckBox
        function useDifferentScalingFactorsChanged(app, event)
            value = app.UsedifferentXandYscalingfactorsCheckBox.Value;
            app.YscalingfactorEditField.Visible = value;
            app.ForcecoefficientYscalingfactorEditFieldLabel.Visible = value;
            if (~value)
                app.ScalingfactorLabel.Text = 'Scaling factor:';
            else
                app.ScalingfactorLabel.Text = 'Slip (X) scaling factor:';
            end
        end

        % Value changed function: CreatefitsCheckBox
        function createFitsChanged(app, event)
            if app.CreatefitsCheckBox.Value
                app.FitOptionsPanel.Enable = 'on';
                app.SaveFyandMzdataCheckBox.Enable = 'on';
            else
                app.FitOptionsPanel.Enable = 'off';
                app.SaveFyandMzdataCheckBox.Enable = 'off';
            end
        end

        % Value changed function: TireDropDown
        function tireDropDownChanged(app, event)
            value = app.TireDropDown.Value;
            update_tire_details(app, value);
            suggest_load_bound(app);
        end

        % Value changed function: FZPDropDown
        function LoadSensPDropDownChanged(app, event)
            update_loadsens_axes_auto(app);
        end

        % Value changed function: FZIADropDown
        function LoadSensIADropDownChanged(app, event)
            update_loadsens_axes_auto(app);
        end

        % Callback function
        function LoadSensSAdirChanged(app, event)
            %update_loadsens_axes_auto(app);
        end

        % Value changed function: SASlider
        function LoadSensSASliderChanged(app, event)
            %disp('Function called')
            new_val = event.Value;
            update_loadsens_axes_w_round(app, new_val);
        end

        % Value changed function: SeparateSACheckBox
        function separateSAValueChanged(app, event)
            value = app.SeparateSACheckBox.Value;
            app.SaveFyandMzdataCheckBox.Value = false;
            app.SaveFyandMzdataCheckBox.Enable = ~value;
        end

        % Value changed function: PacejkaVaryDropDown
        function pacejkaVaryButtonPushed(app, event)
            update_pacejka_IAorPState_from_dropdown(app);
            update_pacejka_dropdowns(app);
            update_pacejka_axes(app);
        end

        % Value changed function: PacejkaBothIADropDown, 
        % PacejkaOnlyIADropDown
        function pacejkaOnlyIADropDownChanged(app, event)
            update_pacejka_axes(app);
        end

        % Value changed function: PacejkaOnlyPDropDown
        function pacejkaOnlyPDropDownChanged(app, event)
            disp('Changed P drop down')
            disp('Items:')
            disp(app.PacejkaOnlyPDropDown.Items)
            disp('Value:')
            disp(app.PacejkaOnlyPDropDown.Value)
            update_pacejka_axes(app);
        end

        % Value changed function: FZVaryDropDown
        function loadsens_varydropdown_changed(app, event)
            update_loadsens_IAorPState_from_dropdown(app);
            update_loadsens_dropdowns(app);
            update_loadsens_axes_auto(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TireAnalyzerUIFigure = uifigure('Visible', 'off');
            app.TireAnalyzerUIFigure.Name = 'Tire Analyzer';
            app.TireAnalyzerUIFigure.Icon = 'RFRlogo.png';
            app.TotalGridLayout = uigridlayout(app.TireAnalyzerUIFigure);
            app.BottomGridLayout = uigridlayout(app.TotalGridLayout);
            app.RunOptionsPanel = uipanel(app.BottomGridLayout);
            app.GridLayout8 = uigridlayout(app.RunOptionsPanel);
            app.Panel_21 = uipanel(app.GridLayout8);
            app.CreatefitsCheckBox = uicheckbox(app.Panel_21);
            app.CreatefitsCheckBox.ValueChangedFcn = createCallbackFcn(app, @createFitsChanged, true);
            app.CreatefitsCheckBox.Text = 'Create fits';
            app.CreatefitsCheckBox.Value = true;
            app.Panel_20 = uipanel(app.GridLayout8);
            app.SaveFyandMzdataCheckBox = uicheckbox(app.Panel_20);
            app.SaveFyandMzdataCheckBox.Text = 'Save Fy and Mz data';
            app.Panel_19 = uipanel(app.GridLayout8);
            app.TireDropDown = uidropdown(app.Panel_19);
            app.TireDropDown.Items = {'-'};
            app.TireDropDown.ValueChangedFcn = createCallbackFcn(app, @tireDropDownChanged, true);
            app.TireDropDown.Value = '-';
            app.TireLabel = uilabel(app.Panel_19);
            app.TireLabel.Text = 'Tire:';
            app.Panel_11 = uipanel(app.GridLayout8);
            app.GridLayout7 = uigridlayout(app.Panel_11);
            app.RoundCheckBox = uicheckbox(app.GridLayout7);
            app.RoundCheckBox.Enable = 'off';
            app.RoundCheckBox.Text = '';
            app.ManufacturerCheckBox = uicheckbox(app.GridLayout7);
            app.ManufacturerCheckBox.Enable = 'off';
            app.ManufacturerCheckBox.Text = '';
            app.ODCheckbox = uicheckbox(app.GridLayout7);
            app.ODCheckbox.Enable = 'off';
            app.ODCheckbox.Text = '';
            app.TireWidthCheckBox = uicheckbox(app.GridLayout7);
            app.TireWidthCheckBox.Enable = 'off';
            app.TireWidthCheckBox.Text = '';
            app.RimODCheckBox = uicheckbox(app.GridLayout7);
            app.RimODCheckBox.Enable = 'off';
            app.RimODCheckBox.Text = '';
            app.CompoundCheckBox = uicheckbox(app.GridLayout7);
            app.CompoundCheckBox.Enable = 'off';
            app.CompoundCheckBox.Text = '';
            app.RimWidthCheckBox = uicheckbox(app.GridLayout7);
            app.RimWidthCheckBox.Enable = 'off';
            app.RimWidthCheckBox.Text = '';
            app.Panel_12 = uipanel(app.GridLayout7);
            app.RoundDropDownLabel = uilabel(app.Panel_12);
            app.RoundDropDownLabel.Enable = 'off';
            app.RoundDropDownLabel.Text = 'Round:';
            app.RoundDropDown = uidropdown(app.Panel_12);
            app.RoundDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.RoundDropDown.Enable = 'off';
            app.RoundDropDown.Value = '1';
            app.Panel_13 = uipanel(app.GridLayout7);
            app.ManufacturerDropDownLabel = uilabel(app.Panel_13);
            app.ManufacturerDropDownLabel.Enable = 'off';
            app.ManufacturerDropDownLabel.Text = 'Manufacturer:';
            app.ManufacturerDropDown = uidropdown(app.Panel_13);
            app.ManufacturerDropDown.Items = {'Avon', 'Continental', 'Dunlop', 'Goodyear', 'Hoosier', 'Michelin'};
            app.ManufacturerDropDown.Enable = 'off';
            app.ManufacturerDropDown.Value = 'Avon';
            app.Panel_14 = uipanel(app.GridLayout7);
            app.OuterdiameterDropDownLabel = uilabel(app.Panel_14);
            app.OuterdiameterDropDownLabel.Enable = 'off';
            app.OuterdiameterDropDownLabel.Text = 'Outer diameter:';
            app.OuterdiameterDropDown = uidropdown(app.Panel_14);
            app.OuterdiameterDropDown.Items = {'-'};
            app.OuterdiameterDropDown.Enable = 'off';
            app.OuterdiameterDropDown.Value = '-';
            app.Panel_15 = uipanel(app.GridLayout7);
            app.TirewidthDropDownLabel = uilabel(app.Panel_15);
            app.TirewidthDropDownLabel.Enable = 'off';
            app.TirewidthDropDownLabel.Text = 'Tire width:';
            app.TirewidthDropDown = uidropdown(app.Panel_15);
            app.TirewidthDropDown.Items = {'-'};
            app.TirewidthDropDown.Enable = 'off';
            app.TirewidthDropDown.Value = '-';
            app.Panel_16 = uipanel(app.GridLayout7);
            app.RimouterdiameterDropDownLabel = uilabel(app.Panel_16);
            app.RimouterdiameterDropDownLabel.Enable = 'off';
            app.RimouterdiameterDropDownLabel.Text = 'Rim outer diameter:';
            app.RimouterdiameterDropDown = uidropdown(app.Panel_16);
            app.RimouterdiameterDropDown.Items = {'-'};
            app.RimouterdiameterDropDown.Enable = 'off';
            app.RimouterdiameterDropDown.Value = '-';
            app.Panel_17 = uipanel(app.GridLayout7);
            app.CompoundDropDownLabel = uilabel(app.Panel_17);
            app.CompoundDropDownLabel.Enable = 'off';
            app.CompoundDropDownLabel.Text = 'Compound:';
            app.CompoundDropDown = uidropdown(app.Panel_17);
            app.CompoundDropDown.Items = {'-'};
            app.CompoundDropDown.Enable = 'off';
            app.CompoundDropDown.Value = '-';
            app.Panel_18 = uipanel(app.GridLayout7);
            app.RimwidthDropDownLabel = uilabel(app.Panel_18);
            app.RimwidthDropDownLabel.Enable = 'off';
            app.RimwidthDropDownLabel.Text = 'Rim width:';
            app.RimwidthDropDown = uidropdown(app.Panel_18);
            app.RimwidthDropDown.Items = {'-'};
            app.RimwidthDropDown.Enable = 'off';
            app.RimwidthDropDown.Value = '-';
            app.RunButton = uibutton(app.GridLayout8, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @runButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ButtonGroup = uibuttongroup(app.GridLayout8);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @tireSelectionMethodChanged, true);
            app.SelectfromdropdownButton = uiradiobutton(app.ButtonGroup);
            app.SelectfromdropdownButton.Text = 'Select from dropdown';
            app.SelectfromdropdownButton.Value = true;
            app.SelectindividualparametersButton = uiradiobutton(app.ButtonGroup);
            app.SelectindividualparametersButton.Enable = 'off';
            app.SelectindividualparametersButton.Text = 'Select individual parameters';
            app.FitOptionsPanel = uipanel(app.GridLayout8);
            app.FitOptionsPanel.Title = 'Fit Options';
            app.LoadsensitivityorderLabel = uilabel(app.FitOptionsPanel);
            app.LoadsensitivityorderLabel.Text = 'Load sensitivity order:';
            app.LoadSensitivityOrderSpinner = uispinner(app.FitOptionsPanel);
            app.LoadSensitivityOrderSpinner.Limits = [1 4];
            app.LoadSensitivityOrderSpinner.Tooltip = {'Polynomial order that will be fit to the load sensitivity data.'};
            app.LoadSensitivityOrderSpinner.Value = 2;
            app.SlipanglebounddegLabel = uilabel(app.FitOptionsPanel);
            app.SlipanglebounddegLabel.Text = 'Slip angle bound (deg):';
            app.SABoundEditField = uieditfield(app.FitOptionsPanel, 'numeric');
            app.SABoundEditField.Value = 15;
            app.SlipanglestepsizedegEditFieldLabel = uilabel(app.FitOptionsPanel);
            app.SlipanglestepsizedegEditFieldLabel.Text = 'Slip angle step size (deg):';
            app.SAStepSizeEditField = uieditfield(app.FitOptionsPanel, 'numeric');
            app.SAStepSizeEditField.Value = 0.1;
            app.LoadstepsizeNEditFieldLabel = uilabel(app.FitOptionsPanel);
            app.LoadstepsizeNEditFieldLabel.Text = 'Load step size (N):';
            app.LoadStepSizeEditField = uieditfield(app.FitOptionsPanel, 'numeric');
            app.LoadStepSizeEditField.Value = 20;
            app.LoadupperboundNEditFieldLabel = uilabel(app.FitOptionsPanel);
            app.LoadupperboundNEditFieldLabel.Text = 'Load upper bound (N):';
            app.LoadUBEditField = uieditfield(app.FitOptionsPanel, 'numeric');
            app.LoadUBEditField.Value = 1200;
            app.PacejkafitmaxiterationsEditFieldLabel = uilabel(app.FitOptionsPanel);
            app.PacejkafitmaxiterationsEditFieldLabel.Text = 'Pacejka fit max iterations:';
            app.PacejkafitmaxiterationsEditField = uieditfield(app.FitOptionsPanel, 'numeric');
            app.PacejkafitmaxiterationsEditField.Value = 3000;
            app.PacejkaalgorithmDropDownLabel = uilabel(app.FitOptionsPanel);
            app.PacejkaalgorithmDropDownLabel.Text = 'Pacejka algorithm:';
            app.PacejkaalgorithmDropDown = uidropdown(app.FitOptionsPanel);
            app.PacejkaalgorithmDropDown.Items = {'trust-region-reflective', 'levenberg-marquardt'};
            app.PacejkaalgorithmDropDown.Value = 'levenberg-marquardt';
            app.SeparateSACheckBox = uicheckbox(app.FitOptionsPanel);
            app.SeparateSACheckBox.ValueChangedFcn = createCallbackFcn(app, @separateSAValueChanged, true);
            app.SeparateSACheckBox.Enable = 'off';
            app.SeparateSACheckBox.Text = 'Separate increasing/decreasing SA';
            app.ScalingfactorsPanel = uipanel(app.GridLayout8);
            app.ScalingfactorsPanel.Title = 'Scaling factors';
            app.UsedifferentXandYscalingfactorsCheckBox = uicheckbox(app.ScalingfactorsPanel);
            app.UsedifferentXandYscalingfactorsCheckBox.ValueChangedFcn = createCallbackFcn(app, @useDifferentScalingFactorsChanged, true);
            app.UsedifferentXandYscalingfactorsCheckBox.Text = 'Use different X and Y scaling factors';
            app.ScalingfactorLabel = uilabel(app.ScalingfactorsPanel);
            app.ScalingfactorLabel.Text = 'Scaling factor:';
            app.XscalingfactorEditField = uieditfield(app.ScalingfactorsPanel, 'numeric');
            app.XscalingfactorEditField.Limits = [0 Inf];
            app.XscalingfactorEditField.Value = 1;
            app.ForcecoefficientYscalingfactorEditFieldLabel = uilabel(app.ScalingfactorsPanel);
            app.ForcecoefficientYscalingfactorEditFieldLabel.Visible = 'off';
            app.ForcecoefficientYscalingfactorEditFieldLabel.Text = 'Force coefficient (Y) scaling factor:';
            app.YscalingfactorEditField = uieditfield(app.ScalingfactorsPanel, 'numeric');
            app.YscalingfactorEditField.Limits = [0 Inf];
            app.YscalingfactorEditField.Visible = 'off';
            app.YscalingfactorEditField.Value = 1;
            app.PlotsPanel = uipanel(app.BottomGridLayout);
            app.GridLayout = uigridlayout(app.PlotsPanel);
            app.PacejkaFitsPanel = uipanel(app.GridLayout);
            app.PacejkaFitsPanel.Title = 'Pacejka fits';
            app.GridLayout3 = uigridlayout(app.PacejkaFitsPanel);
            app.PacejkaClickRunLabel = uilabel(app.GridLayout3);
            app.PacejkaClickRunLabel.Text = 'Click Run to view graphs';
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.Panel_5 = uipanel(app.GridLayout4);
            app.PacejkaVaryDropDownLabel = uilabel(app.Panel_5);
            app.PacejkaVaryDropDownLabel.Text = 'Vary:';
            app.PacejkaVaryDropDown = uidropdown(app.Panel_5);
            app.PacejkaVaryDropDown.Items = {'Inc. angle', 'Pressure'};
            app.PacejkaVaryDropDown.ValueChangedFcn = createCallbackFcn(app, @pacejkaVaryButtonPushed, true);
            app.PacejkaVaryDropDown.Value = 'Inc. angle';
            app.Panel_6 = uipanel(app.GridLayout4);
            app.PacejkaBothIADropDown = uidropdown(app.Panel_6);
            app.PacejkaBothIADropDown.Items = {'-'};
            app.PacejkaBothIADropDown.ValueChangedFcn = createCallbackFcn(app, @pacejkaOnlyIADropDownChanged, true);
            app.PacejkaBothIADropDown.Visible = 'off';
            app.PacejkaBothIADropDown.Value = '-';
            app.PacejkaBothIADropDownLabel = uilabel(app.Panel_6);
            app.PacejkaBothIADropDownLabel.Visible = 'off';
            app.PacejkaBothIADropDownLabel.Text = 'IA (deg):';
            app.PacejkaOnlyIADropDown = uidropdown(app.Panel_6);
            app.PacejkaOnlyIADropDown.Items = {'-'};
            app.PacejkaOnlyIADropDown.ValueChangedFcn = createCallbackFcn(app, @pacejkaOnlyIADropDownChanged, true);
            app.PacejkaOnlyIADropDown.Value = '-';
            app.PacejkaOnlyIADropDownLabel = uilabel(app.Panel_6);
            app.PacejkaOnlyIADropDownLabel.Text = 'IA (deg):';
            app.Panel_7 = uipanel(app.GridLayout4);
            app.PacejkaBothPDropDown = uidropdown(app.Panel_7);
            app.PacejkaBothPDropDown.Items = {'-'};
            app.PacejkaBothPDropDown.ValueChangedFcn = createCallbackFcn(app, @pacejkaBothPDropDownValueChanged, true);
            app.PacejkaBothPDropDown.Visible = 'off';
            app.PacejkaBothPDropDown.Value = '-';
            app.PacejkaBothPDropDownLabel = uilabel(app.Panel_7);
            app.PacejkaBothPDropDownLabel.Visible = 'off';
            app.PacejkaBothPDropDownLabel.Text = 'P (psi):';
            app.PacejkaOnlyPDropDown = uidropdown(app.Panel_7);
            app.PacejkaOnlyPDropDown.Items = {'-'};
            app.PacejkaOnlyPDropDown.ValueChangedFcn = createCallbackFcn(app, @pacejkaOnlyPDropDownChanged, true);
            app.PacejkaOnlyPDropDown.Value = '-';
            app.PacejkaOnlyPDropDownLabel = uilabel(app.Panel_7);
            app.PacejkaOnlyPDropDownLabel.Text = 'P (psi):';
            app.Panel_8 = uipanel(app.GridLayout4);
            app.PacejkaFZDropDown = uidropdown(app.Panel_8);
            app.PacejkaFZDropDown.Items = {'-'};
            app.PacejkaFZDropDown.ValueChangedFcn = createCallbackFcn(app, @pacejkaFZDropDownValueChanged, true);
            app.PacejkaFZDropDown.Value = '-';
            app.PacejkaFZDropDownLabel = uilabel(app.Panel_8);
            app.PacejkaFZDropDownLabel.Text = 'FZ (N):';
            app.Panel_9 = uipanel(app.GridLayout4);
            app.PacejkaSAdirDropDown = uidropdown(app.Panel_9);
            app.PacejkaSAdirDropDown.Items = {'+', '-'};
            app.PacejkaSAdirDropDown.Enable = 'off';
            app.PacejkaSAdirDropDown.Value = '+';
            app.PacejkaSAdirDropDownLabel = uilabel(app.Panel_9);
            app.PacejkaSAdirDropDownLabel.Enable = 'off';
            app.PacejkaSAdirDropDownLabel.Text = 'SA dir:';
            app.PacejkaFitNotCompletedLabel = uilabel(app.GridLayout3);
            app.PacejkaFitNotCompletedLabel.Text = 'Fit not completed        ';
            app.PacejkaFitAxes = uiaxes(app.GridLayout3);
            title(app.PacejkaFitAxes, 'Pacejka fit')
            xlabel(app.PacejkaFitAxes, 'Slip angle (deg)')
            ylabel(app.PacejkaFitAxes, 'Lateral coefficient')
            app.CamberSensitivityPanel = uipanel(app.GridLayout);
            app.CamberSensitivityPanel.Title = 'Camber sensitivity';
            app.GridLayout9 = uigridlayout(app.CamberSensitivityPanel);
            app.NotimplementedyetLabel = uilabel(app.GridLayout9);
            app.NotimplementedyetLabel.Text = 'Not implemented yet';
            app.PressureSensitivityPanel = uipanel(app.GridLayout);
            app.PressureSensitivityPanel.Title = 'Pressure sensitivity';
            app.GridLayout10 = uigridlayout(app.PressureSensitivityPanel);
            app.NotimplementedyetLabel_2 = uilabel(app.GridLayout10);
            app.NotimplementedyetLabel_2.Text = 'Not implemented yet';
            app.LoadSensitivityPanel = uipanel(app.GridLayout);
            app.LoadSensitivityPanel.Title = 'Load sensitivity';
            app.GridLayout2 = uigridlayout(app.LoadSensitivityPanel);
            app.GridLayout2_2 = uigridlayout(app.GridLayout2);
            app.Panel = uipanel(app.GridLayout2_2);
            app.VaryDropDownLabel = uilabel(app.Panel);
            app.VaryDropDownLabel.Text = 'Vary:';
            app.FZVaryDropDown = uidropdown(app.Panel);
            app.FZVaryDropDown.Items = {'Inc. angle', 'Pressure'};
            app.FZVaryDropDown.ValueChangedFcn = createCallbackFcn(app, @loadsens_varydropdown_changed, true);
            app.FZVaryDropDown.Value = 'Inc. angle';
            app.Panel_2 = uipanel(app.GridLayout2_2);
            app.IAdegDropDown_2Label = uilabel(app.Panel_2);
            app.IAdegDropDown_2Label.Text = 'IA (deg):';
            app.FZIADropDown = uidropdown(app.Panel_2);
            app.FZIADropDown.Items = {'0', '2', '4'};
            app.FZIADropDown.ValueChangedFcn = createCallbackFcn(app, @LoadSensIADropDownChanged, true);
            app.FZIADropDown.Value = '0';
            app.Panel_3 = uipanel(app.GridLayout2_2);
            app.PpsiDropDown_2Label = uilabel(app.Panel_3);
            app.PpsiDropDown_2Label.Text = 'P (psi):';
            app.FZPDropDown = uidropdown(app.Panel_3);
            app.FZPDropDown.Items = {'10', '12', '14'};
            app.FZPDropDown.ValueChangedFcn = createCallbackFcn(app, @LoadSensPDropDownChanged, true);
            app.FZPDropDown.Value = '10';
            app.Panel_4 = uipanel(app.GridLayout2_2);
            app.SAdirDropDownLabel = uilabel(app.Panel_4);
            app.SAdirDropDownLabel.Enable = 'off';
            app.SAdirDropDownLabel.Text = 'SA dir:';
            app.FZSAdirDropDown = uidropdown(app.Panel_4);
            app.FZSAdirDropDown.Items = {'+', '-'};
            app.FZSAdirDropDown.Enable = 'off';
            app.FZSAdirDropDown.Value = '+';
            app.GridLayout6 = uigridlayout(app.GridLayout2);
            app.LBdegLabel = uilabel(app.GridLayout6);
            app.LBdegLabel.Text = '-15 deg';
            app.SASlider = uislider(app.GridLayout6);
            app.SASlider.Limits = [-15 15];
            app.SASlider.MajorTicks = [-15 0 15];
            app.SASlider.MajorTickLabels = {''};
            app.SASlider.ValueChangedFcn = createCallbackFcn(app, @LoadSensSASliderChanged, true);
            app.UBdegLabel = uilabel(app.GridLayout6);
            app.UBdegLabel.Text = '15 deg';
            app.FZClickRunLabel = uilabel(app.GridLayout2);
            app.FZClickRunLabel.Text = 'Click Run to view graphs';
            app.FZAxes = uiaxes(app.GridLayout2);
            title(app.FZAxes, 'Load sensitivity and fit at SA=Xdeg')
            xlabel(app.FZAxes, 'Load (N)')
            ylabel(app.FZAxes, 'Lateral coefficient')
            app.FZAxes.YLim = [-3.2 3.2];
            app.TopGridLayout = uigridlayout(app.TotalGridLayout);
            app.TitlePanel = uipanel(app.TopGridLayout);
            app.GridLayout11 = uigridlayout(app.TitlePanel);
            app.Panel_22 = uipanel(app.GridLayout11);
            app.LoadingImage = uiimage(app.Panel_22);
            app.LoadingImage.ImageSource = 'loading.gif';
            app.Panel_23 = uipanel(app.GridLayout11);
            app.RFRImage = uiimage(app.Panel_23);
            app.RFRImage.ImageSource = 'RFRlogo.png';
            app.TireAnalyzerLabel = uilabel(app.Panel_23);
            app.TireAnalyzerLabel.Text = 'Tire Analyzer';
            app.ByMatthewDrummondStoylesLabel = uilabel(app.Panel_23);
            app.ByMatthewDrummondStoylesLabel.Text = 'By Matthew Drummond-Stoyles';
            app.SeesourcecodeLabel = uilabel(app.Panel_23);
            app.SeesourcecodeLabel.Text = 'See source code:';
            app.EditField = uieditfield(app.Panel_23, 'text');
            app.EditField.Editable = 'off';
            app.EditField.Value = 'https://github.com/yafta98/TireAnalyzer';
            app.StatusPanel = uipanel(app.TopGridLayout);
            app.StatusPanel.Title = 'Status';
            app.PrimaryStatusLabel = uilabel(app.StatusPanel);
            app.PrimaryStatusLabel.Text = '';
            app.SecondaryStatusLabel = uilabel(app.StatusPanel);
            app.SecondaryStatusLabel.Text = '';
            app.TertiaryStatusLabel = uilabel(app.StatusPanel);
            app.TertiaryStatusLabel.Text = '';
            % Show the figure after all components are created
            app.TireAnalyzerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TireAnalyzer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TireAnalyzerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TireAnalyzerUIFigure)
        end
    end
end